classdef CiftiStorm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        NewprocessMenu          matlab.ui.container.Menu
        DatasetMenu             matlab.ui.container.Menu
        DatasetLoadMenu         matlab.ui.container.Menu
        DatasetListMenu         matlab.ui.container.Menu
        DatasetDeleteMenu       matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        ToolsMenu               matlab.ui.container.Menu
        FreesurferMenu          matlab.ui.container.Menu
        CiftifyMenu             matlab.ui.container.Menu
        MEEGprepMenu            matlab.ui.container.Menu
        BCVARETAMenu            matlab.ui.container.Menu
        ViewMenu                matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        UpdateMenu              matlab.ui.container.Menu
        UsermanualMenu          matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        Toolbar                 matlab.ui.container.Toolbar
        PushToolNewProcess      matlab.ui.container.toolbar.PushTool
        PushToolDefault_Params  matlab.ui.container.toolbar.PushTool
        PushToolRunProcess      matlab.ui.container.toolbar.PushTool
        PushToolListDataset     matlab.ui.container.toolbar.PushTool
        PushToolExplorerDS      matlab.ui.container.toolbar.PushTool
        LabelBreadcrumbs        matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        TabExplorer             matlab.ui.container.Tab
        TreeInput               matlab.ui.container.Tree
        ProtocolsTab            matlab.ui.container.Tab
        ProtocolsDropDown       matlab.ui.control.DropDown
        ProtocolsDropDownLabel  matlab.ui.control.Label
        TreeProtocol            matlab.ui.container.Tree
        TabDatasets             matlab.ui.container.Tab
        DatasetsDropDown        matlab.ui.control.DropDown
        DatasetsDropDownLabel   matlab.ui.control.Label
        TreeDatasets            matlab.ui.container.Tree
    end


    properties (Access = private)
        cfs_properties % Description
        ciftistormDir % Description
        bst_file % Description
        Datasets % Description
        currentDT % Description
    end

    methods (Access = private)
        % Explore the Tree Folder
        function ExploreFolder(app,nodeData)
            app.TreeInput.Children.delete;
            if(ismember(nodeData.name,{'..'}))
                rootDir = fileparts(nodeData.folder);
                dirName = strsplit(rootDir,filesep);
                dirName = dirName{end};
            else
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
            end
            app.Breadcrumbs(rootDir)
            parent = uitreenode(app.TreeInput,'Text',dirName);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(rootDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                    end
                    % Contex menu
                    cm = uicontextmenu(app.UIFigure);
                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',node.NodeData});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',node.NodeData});
                    node.ContextMenu = cm;
                    node.Icon = icon_file;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
        end
        % Create the Breadcrumbs in the navigation tree
        function Breadcrumbs(app,path)
            pathParts = strsplit(path,filesep);
            text = strcat(filesep, " ");
            for i=2:length(pathParts)
                if(i < length(pathParts))
                    text = strcat(text, pathParts{i}," ",filesep," ");
                else
                    text = strcat(text, pathParts{i});
                end
            end
            app.LabelBreadcrumbs.Text = strcat("<font style='color:blue;'>",text,"</font>");
            app.LabelBreadcrumbs.Interpreter = 'html';
        end
        % Fill a TreeNode with subfolders
        function FillFolder(app,node)
            nodeData = node.NodeData;
            if(~ismember(nodeData.name,{'..'}) && ~ismember(nodeData.name,{'.'}))
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
                subfile_list = dir(rootDir);
                subfile_list(ismember({subfile_list.name},{'.','..'})) = [];
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/folder.png';
                            subnode.NodeData = subfile_list(j);
                            sub_cm = uicontextmenu(app.UIFigure);
                            m1 = uimenu(sub_cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',node.NodeData});
                            m2 = uimenu(sub_cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',node.NodeData});
                            subnode.ContextMenu = sub_cm;
                        end
                    end
                end
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(~subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/file.jpeg';
                            subnode.NodeData = subfile_list(j);
                        end
                    end
                end
            end
        end    

    end

    methods (Access = public)

        function DefineProperties(app,event,action,clasif,nodeData)
            switch clasif
                case 'Output'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    app.cfs_properties.general_params.output_path = base_path;
                    saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Anatomy'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    structures = dir(base_path);
                    structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                    count = 0;
                    for i=1:length(structures)
                        structure = structures(i);
                        if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc_restore.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc_restore.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc.nii.gz')))
                            count = count + 1;
                        end
                    end
                    if(isequal(count,length(structures)))
                        msgbox({'The selected folder do not contain any Ciftify structure',...
                            'Please select a correct anatomy folder.'},'Info');
                        return;
                    end
                    if(count > 0)
                        msgbox({'One or more subject forders are not a HCP structure.',...
                            'Please check the Anatomy folder or you can continue in this way.'},'Info');
                    end
                    app.cfs_properties.anatomy_params.anatomy_type.type_list{2}.base_path = base_path;
                    saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Non-brain'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    structures = dir(base_path);
                    structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                    count = 0;
                    for i=1:length(structures)
                        structure = structures(i);
                        if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))...
                                || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                                || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                            count = count + 1;
                        end
                    end
                    if(isequal(count,length(structures)))
                        msgbox({'The selected folder do not contain any FSL Bet structure',...
                            'Please select a correct anatomy folder.'},'Info');
                        return;
                    end
                    if(count > 0)
                        msgbox({'One or more subject forders are not a FSL Bet structure.',...
                            'Please check the FSL non-brain surfaces folder or you can continue in this way.'},'Info');
                    end
                    app.cfs_properties.anatomy_params.common_params.non_brain_surfaces.base_path = base_path;
                    saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
                    h.smartIndentContents
                    h.save
                    h.close
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");            
            app.cfs_properties = get_properties();
            userDir = getUserDir();
            app.Breadcrumbs(userDir);
            parent = uitreenode(app.TreeInput,'Text',userDir);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(userDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', fileStruct.name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                    end
                    % Context menu
                    cm = uicontextmenu(app.UIFigure);
                    mo = uimenu(cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',fileStruct},'Separator','on');

                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@app.DefineProperties,'Anatomy',fileStruct});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@app.DefineProperties,'Non-brain',fileStruct});
                    node.ContextMenu = cm;
                    node.Icon = icon_file;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', fileStruct.name);
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);

            %% Load Protocols
            general_params = app.cfs_properties.general_params;
            if(cfs_check_properties('BST_path',general_params.bst_config.bst_path) && cfs_check_properties('BST_db',general_params.bst_config.db_path))
                if(isequal(general_params.bst_config.db_path,'local'))
                    bst_db = fullfile(userDir,'.brainstorm');
                else
                    [bst_db,~,~] = fileparts(general_params.bst_config.db_path);
                end
                if(isfile(fullfile(bst_db,'brainstorm.mat')))
                    app.bst_file = fullfile(bst_db,'brainstorm.mat');
                    bst_info = load(app.bst_file);
                    protocols = bst_info.ProtocolsListInfo;
                    app.ProtocolsDropDown.Items = {protocols.Comment};
                    app.ProtocolsDropDown.Items{end+1} = '--Select--';
                    app.ProtocolsDropDown.Value = '--Select--';
                end
            end

            %% Load Datasets
            app.Datasets = cfs_get('datasets');
            if(~isempty(app.Datasets))
                app.DatasetsDropDown.Items = {app.Datasets.Name};
                app.DatasetsDropDown.ItemsData = {app.Datasets.UUID};
                app.DatasetsDropDown.Items{end+1} = '--Select--';
                app.DatasetsDropDown.ItemsData{end+1} = '--Select--';
                app.DatasetsDropDown.Value = '--Select--';
            end

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: NewprocessMenu
        function NewprocessMenuSelected(app, event)
            obj_configure = Params_configuration();
        end

        % Double-clicked callback: TreeInput
        function TreeInputDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                ExploreFolder(app,node.NodeData);
            end
        end

        % Clicked callback: TreeInput
        function TreeInputClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                FillFolder(app,node);
            end
        end

        % Callback function: PushToolRunProcess
        function PushToolRunProcessClicked(app, event)
            app.cfs_properties = get_properties('run');
            [~, reject_subjects]     = check_properties(app.cfs_properties);

            %% BrainStorm configuration
            disp('-->> Preparing BrainStorm properties.');
            disp('==========================================================================');
            bst_path        = app.cfs_properties.general_params.bst_config.bst_path;
            addpath(genpath(bst_path));
            status          = starting_brainstorm(app.cfs_properties);

            %% Calling dataset function to analysis
            if(status)
                process_error = cfs_process_interface(app.cfs_properties,reject_subjects);
                % save("process_output.mat","process_error","reject_subjects");
            end

            %% Stopping BrainStorm
            disp('==========================================================================');
            brainstorm('stop');
            disp('==========================================================================');
            disp(properties.generals.name);
            disp('==========================================================================');
            close all;
            clear all;
        end

        % Callback function: PushToolNewProcess
        function PushToolNewProcessClicked(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');
            obj_configure = Params_configuration();
            waitfor(obj_configure);
            close(d)
            figure(app.UIFigure);
        end

        % Value changed function: ProtocolsDropDown
        function ProtocolsDropDownValueChanged(app, event)
            delete(app.TreeProtocol.Children);
            comment = app.ProtocolsDropDown.Value;
            bst_info = load(app.bst_file);
            protocols = bst_info.ProtocolsListInfo;
            protocol_info = protocols(find(ismember({protocols.Comment},comment),1));
            protocol = load(fullfile(protocol_info.STUDIES,'protocol.mat'));
            subjects = protocol.ProtocolSubjects.Subject;
            for i=1:length(subjects)
                subject = subjects(i);
                node = uitreenode(app.TreeProtocol, 'Text', subject.Name);
                node.NodeData = subject;
                icon_file = 'guide/images/subject.png';
                node.Icon = icon_file;

                anat = subject.Anatomy(subject.iAnatomy);
                if(~isempty(anat))
                    subnode = uitreenode(node, 'Text', anat.Comment);
                    subnode.Icon = 'guide/images/MRI.png';
                    subnode.NodeData = anat;
                end

                scalp = subject.Surface(subject.iScalp);
                if(~isempty(scalp))
                    subnode = uitreenode(node, 'Text', scalp.Comment);
                    subnode.Icon = 'guide/images/scalp.png';
                    subnode.NodeData = scalp;
                end

                outer = subject.Surface(subject.iOuterSkull);
                if(~isempty(outer))
                    subnode = uitreenode(node, 'Text', outer.Comment);
                    subnode.Icon = 'guide/images/outerskull.png';
                    subnode.NodeData = outer;
                end

                inner = subject.Surface(subject.iInnerSkull);
                if(~isempty(inner))
                    subnode = uitreenode(node, 'Text', inner.Comment);
                    subnode.Icon = 'guide/images/innerskull.png';
                    subnode.NodeData = inner;
                end

                cortex = subject.Surface(subject.iCortex);
                if(~isempty(cortex))
                    subnode = uitreenode(node, 'Text', cortex.Comment);
                    subnode.Icon = 'guide/images/cortex_tree.png';
                    subnode.NodeData = cortex;
                end
            end
        end

        % Callback function: PushToolDefault_Params
        function PushToolDefault_ParamsClicked(app, event)
            opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Yes';
            % Use the TeX interpreter to format the question
            quest = {strcat("You will restore the default params"), ...
                'Would you like continue?'};
            answer = questdlg(quest,'Apply changes',...
                'Yes','No',opts);
            % Handle response
            switch answer
                case 'Yes'
                    set_default_properties(app);
                case 'No'
            end
        end

        % Menu selected function: MEEGprepMenu
        function MEEGprepMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');            
            meegprep;
            waitfor(obj_configure);
            close(d)
            figure(app.UIFigure);
        end

        % Menu selected function: BCVARETAMenu
        function BCVARETAMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');            
            bcvareta;
            waitfor(obj_configure);
            close(d)
            figure(app.UIFigure);
        end

        % Value changed function: DatasetsDropDown
        function DatasetsDropDownValueChanged(app, event)
            value = event.Value;            
            delete(app.TreeDatasets.Children);
            if(isequal(value,'--Select--'))
                 msgbox('Please select a correct Dataset.','Info');
                        return;
            else
                dt_ind = find(ismember({app.Datasets.UUID},value),1);
                app.currentDT = app.Datasets(dt_ind);
                for i=1:length(app.currentDT.Participants)
                    participant = app.currentDT.Participants(i);
                    node = uitreenode(app.TreeDatasets, 'Text', participant.SubID);
                    node.NodeData = participant;
                    icon_file = 'guide/images/subject.png';
                    node.Icon = icon_file;
                end
            end
        end

        % Menu selected function: DatasetLoadMenu
        function DatasetLoadMenuSelected(app, event)
            homedir = char(java.lang.System.getProperty('user.home'));
            d = uiprogressdlg(app.UIFigure,'Title','Loading Dataset......');
            folder = uigetdir(homedir,"Select the Dataset folder");
            if(isequal(folder,0))
                close(d)
                figure(app.UIFigure);
                return;
            end
            tempDT = jsondecode(fileread(fullfile(folder,'CiftiStorm.json')));
            tempDT.Location = fullfile(folder,'CiftiStorm.json');
            if(~isempty(find(ismember({app.Datasets.UUID},tempDT.UUID),1)))
                 msgbox({'The selected Dataset is already in the CiftiStorm Database',...
                            'Would you like to import any way?.'},'Info');
            else
                app.currentDT = tempDT;
                if(isempty(app.Datasets))
                    app.Datasets = app.currentDT;
                else
                    app.Datasets(end + 1) = app.currentDT;
                end
                [icondata,iconcmap] = imread("guide/images/success.png");
                msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                waitfor(msg);
                disp("-->> Dataset loaded");
                cfs_set('datasets',app.Datasets);

                app.DatasetsDropDown.Items = {app.Datasets.Name};
                app.DatasetsDropDown.ItemsData = {app.Datasets.UUID};
                app.DatasetsDropDown.Items{end+1} = '--Select--';
                app.DatasetsDropDown.ItemsData{end+1} = '--Select--';
                app.DatasetsDropDown.Value = app.currentDT.UUID;

                delete(app.TreeDatasets.Children);
                for i=1:length(app.currentDT.Participants)
                    participant = app.currentDT.Participants(i);
                    node = uitreenode(app.TreeDatasets, 'Text', participant.SubID);
                    node.NodeData = participant;
                    icon_file = 'guide/images/subject.png';
                    node.Icon = icon_file;
                end
            end
            close(d)
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewprocessMenu = uimenu(app.FileMenu);
            app.NewprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprocessMenuSelected, true);
            app.NewprocessMenu.Text = 'New process';
            app.DatasetMenu = uimenu(app.FileMenu);
            app.DatasetMenu.Text = 'Dataset';
            app.DatasetLoadMenu = uimenu(app.DatasetMenu);
            app.DatasetLoadMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetLoadMenuSelected, true);
            app.DatasetLoadMenu.Text = 'Load';
            app.DatasetListMenu = uimenu(app.DatasetMenu);
            app.DatasetListMenu.Text = 'List';
            app.DatasetDeleteMenu = uimenu(app.DatasetMenu);
            app.DatasetDeleteMenu.Text = 'Delete';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.FreesurferMenu = uimenu(app.ToolsMenu);
            app.FreesurferMenu.Text = 'Freesurfer';
            app.CiftifyMenu = uimenu(app.ToolsMenu);
            app.CiftifyMenu.Text = 'Ciftify';
            app.MEEGprepMenu = uimenu(app.ToolsMenu);
            app.MEEGprepMenu.MenuSelectedFcn = createCallbackFcn(app, @MEEGprepMenuSelected, true);
            app.MEEGprepMenu.Text = 'MEEGprep';
            app.BCVARETAMenu = uimenu(app.ToolsMenu);
            app.BCVARETAMenu.MenuSelectedFcn = createCallbackFcn(app, @BCVARETAMenuSelected, true);
            app.BCVARETAMenu.Text = 'BC-VARETA';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UpdateMenu = uimenu(app.HelpMenu);
            app.UpdateMenu.Text = 'Update';
            app.UsermanualMenu = uimenu(app.HelpMenu);
            app.UsermanualMenu.Text = 'User manual';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushToolNewProcess = uipushtool(app.Toolbar);
            app.PushToolNewProcess.Tooltip = {'New process'};
            app.PushToolNewProcess.ClickedCallback = createCallbackFcn(app, @PushToolNewProcessClicked, true);
            app.PushToolNewProcess.Icon = fullfile(pathToMLAPP, 'images', 'new_process.jpeg');
            app.PushToolDefault_Params = uipushtool(app.Toolbar);
            app.PushToolDefault_Params.Tooltip = {'Set default params'};
            app.PushToolDefault_Params.ClickedCallback = createCallbackFcn(app, @PushToolDefault_ParamsClicked, true);
            app.PushToolDefault_Params.Icon = fullfile(pathToMLAPP, 'images', 'default.png');
            app.PushToolRunProcess = uipushtool(app.Toolbar);
            app.PushToolRunProcess.Tooltip = {'Run process'};
            app.PushToolRunProcess.ClickedCallback = createCallbackFcn(app, @PushToolRunProcessClicked, true);
            app.PushToolRunProcess.Icon = fullfile(pathToMLAPP, '', 'images', 'run.jpg');
            app.PushToolListDataset = uipushtool(app.Toolbar);
            app.PushToolListDataset.Tooltip = {'List Datasets'};
            app.PushToolListDataset.Icon = fullfile(pathToMLAPP, 'images', 'list_dataset.png');
            app.PushToolExplorerDS = uipushtool(app.Toolbar);
            app.PushToolExplorerDS.Tooltip = {'Explore Datasets'};
            app.PushToolExplorerDS.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabExplorer = uitab(app.TabGroup);
            app.TabExplorer.Title = 'Explorer';
            app.TreeInput = uitree(app.TabExplorer);
            app.TreeInput.ClickedFcn = createCallbackFcn(app, @TreeInputClicked, true);
            app.TreeInput.DoubleClickedFcn = createCallbackFcn(app, @TreeInputDoubleClicked, true);
            app.ProtocolsTab = uitab(app.TabGroup);
            app.ProtocolsTab.Title = 'Protocols';
            app.TreeProtocol = uitree(app.ProtocolsTab);
            app.ProtocolsDropDownLabel = uilabel(app.ProtocolsTab);
            app.ProtocolsDropDownLabel.Text = 'Protocols:';
            app.ProtocolsDropDown = uidropdown(app.ProtocolsTab);
            app.ProtocolsDropDown.Items = {'--Select--'};
            app.ProtocolsDropDown.ValueChangedFcn = createCallbackFcn(app, @ProtocolsDropDownValueChanged, true);
            app.ProtocolsDropDown.Value = '--Select--';
            app.TabDatasets = uitab(app.TabGroup);
            app.TabDatasets.Title = 'Datasets';
            app.TreeDatasets = uitree(app.TabDatasets);
            app.DatasetsDropDownLabel = uilabel(app.TabDatasets);
            app.DatasetsDropDownLabel.Text = 'Datasets:';
            app.DatasetsDropDown = uidropdown(app.TabDatasets);
            app.DatasetsDropDown.Items = {'--Select--'};
            app.DatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetsDropDownValueChanged, true);
            app.DatasetsDropDown.Value = '--Select--';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.LabelBreadcrumbs = uilabel(app.UIFigure);
            app.LabelBreadcrumbs.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CiftiStorm

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end