classdef Dataset_configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        SetpropertiesButton             matlab.ui.control.Button
        ParamsconfigurationPanel        matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GeneralsTab                     matlab.ui.container.Tab
        DependenciesPanel               matlab.ui.container.Panel
        SelectBSTButton                 matlab.ui.control.Button
        SelectBST_dbButton              matlab.ui.control.Button
        SelectSPMButton                 matlab.ui.control.Button
        BraninstormpathEditFieldLabel   matlab.ui.control.Label
        BSTpathEditField                matlab.ui.control.EditField
        BrainstormdbpathEditFieldLabel  matlab.ui.control.Label
        BSTdbpathEditField              matlab.ui.control.EditField
        SPM12pathEditFieldLabel         matlab.ui.control.Label
        SPM12pathEditField              matlab.ui.control.EditField
        OutputconfigurationsPanel       matlab.ui.container.Panel
        SelectWorkspaceButton           matlab.ui.control.Button
        SelectReportsButton             matlab.ui.control.Button
        WorkspacepathEditFieldLabel     matlab.ui.control.Label
        WorkspacepathEditField          matlab.ui.control.EditField
        ReportspathEditFieldLabel       matlab.ui.control.Label
        ReportspathEditField            matlab.ui.control.EditField
        GeneralsparamsPanel             matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ModalityDropDownLabel           matlab.ui.control.Label
        ModalityDropDown                matlab.ui.control.DropDown
        ProtocolnameEditFieldLabel      matlab.ui.control.Label
        ProtocolnameEditField           matlab.ui.control.EditField
        SubjectsperprotocolEditFieldLabel  matlab.ui.control.Label
        SubjectsperprotocolEditField    matlab.ui.control.NumericEditField
        AnatomicalstructureTab          matlab.ui.container.Tab
        AnatomyconfigurationButtonGroup  matlab.ui.container.ButtonGroup
        Button_BST_Default              matlab.ui.control.RadioButton
        Button_HCP_Template             matlab.ui.control.RadioButton
        Button_HCP_Individual           matlab.ui.control.RadioButton
        BSTDefaultPanel                 matlab.ui.container.Panel
        TemplatenameDropDownLabel       matlab.ui.control.Label
        AnatomyTemplateDropDown         matlab.ui.control.DropDown
        HCPTemplatePanel                matlab.ui.container.Panel
        SelectCHPTemplPathButton        matlab.ui.control.Button
        AnatomypathLabel                matlab.ui.control.Label
        HCPTemplPathEditField           matlab.ui.control.EditField
        SelectCHPTemplNBPathButton      matlab.ui.control.Button
        NonbrainsurfacesLabel           matlab.ui.control.Label
        HCPTemplNBPathEditField         matlab.ui.control.EditField
        TemplatenameEditFieldLabel      matlab.ui.control.Label
        HCPTemplatenameEditField        matlab.ui.control.EditField
        HCPIndividualPanel              matlab.ui.container.Panel
        SelectCHPIndividPathButton      matlab.ui.control.Button
        SelectCHPTemplatePathButton_4   matlab.ui.control.Button
        AnatomypathLabel_2              matlab.ui.control.Label
        HCPIndividPathEditField         matlab.ui.control.EditField
        NonbrainsurfacesLabel_2         matlab.ui.control.Label
        HCPIndividNBPathEditField       matlab.ui.control.EditField
        SurfacesresolutionPanel         matlab.ui.container.Panel
        NoverticesofHeadEditFieldLabel  matlab.ui.control.Label
        NoverticesofHeadEditField       matlab.ui.control.NumericEditField
        NoverticesofSkullEditFieldLabel  matlab.ui.control.Label
        NoverticesofSkullEditField      matlab.ui.control.NumericEditField
        NoverticesofCortexEditFieldLabel  matlab.ui.control.Label
        NoverticesofCortexEditField     matlab.ui.control.NumericEditField
        ChannelTab                      matlab.ui.container.Tab
        SelectChanneloptionButtonGroup  matlab.ui.container.ButtonGroup
        Button_Channel_template         matlab.ui.control.RadioButton
        Button_Channel_raw_data         matlab.ui.control.RadioButton
        UseChanneltemplatePanel         matlab.ui.container.Panel
        GroupDropDownLabel              matlab.ui.control.Label
        ChannGroupDropDown              matlab.ui.control.DropDown
        TemplatenameDropDownLabel_2     matlab.ui.control.Label
        ChannTemplatenameDropDown       matlab.ui.control.DropDown
        Channel_UITable                 matlab.ui.control.Table
        RedefinelabelsButton            matlab.ui.control.Button
        UseRawdataPanel                 matlab.ui.container.Panel
        SelectRawDataBPathButton        matlab.ui.control.Button
        BasepathLabel                   matlab.ui.control.Label
        RawDataBPathEditField           matlab.ui.control.EditField
        SelectRawDataFPathButton        matlab.ui.control.Button
        FilelocationLabel               matlab.ui.control.Label
        RawDataFPathEditField           matlab.ui.control.EditField
        FormatDropDownLabel             matlab.ui.control.Label
        FormatDropDown                  matlab.ui.control.DropDown
        PreprocesseddataTab             matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function checked = check_visual_params(app)
            checked = true;
            if(isempty(app.BSTpathEditField.Value))
                msgbox({'The BrainStorm toolbox path can not be empty.',...
                    ' Please select a correct Brainstorm path.'},'Info');
                checked = false;
            end
            if(isempty(app.BSTdbpathEditField.Value))
                msgbox({'The BrainStorm db path can not be empty.',...
                    ' Please select a correct DB path.'},'Info');
                app.BSTdbpathEditField.Value = 'local';
                checked = false;
            end
            if(isempty(app.SPM12pathEditField.Value))
                msgbox({'The SPM12 path can not be empty.',...
                    ' Please select a correct SPM12 path.'},'Info');
                checked = false;
            end
            if(isempty(app.WorkspacepathEditField.Value))
                msgbox({'The Workspace path can not be empty.',...
                    ' Please select a correct Workspace path.'},'Info');
                checked = false;
            end
            if(isempty(app.ReportspathEditField.Value))
                msgbox({'The Reports path can not be empty.',...
                    ' Please select a correct Reports path.'},'Info');
                checked = false;
            end
            if(isempty(app.ProtocolnameEditField.Value))
                msgbox({'The Protocol name field can not be empty.',...
                    ' Please type a correct Protocol name.'},'Info');
                checked = false;
            end
        end       
        
        function load_default_params(app)
            app.BSTDefaultPanel.Enable = 'on';
            app.HCPTemplatePanel.Enable = 'off';
            app.HCPIndividualPanel.Enable = 'off';
            get_bst_default_anatomy(app);
            
            app.UseChanneltemplatePanel.Enable = 'on';
            app.UseRawdataPanel.Enable = 'off';
            get_bst_default_channel(app);
        end
        
        function get_bst_default_anatomy(app)
            % loading anatomical templates
            defaults = jsondecode(fileread(fullfile('bst_templates','bst_default_anatomy.json')));
            app.AnatomyTemplateDropDown.Items = {defaults.name};
            app.AnatomyTemplateDropDown.Items{end+1} = '--Select a template--';
            app.AnatomyTemplateDropDown.Value = '--Select a template--';
        end
        
        function get_bst_default_channel(app)
            defaults = jsondecode(fileread(fullfile('bst_templates','bst_layout_default.json')));
            app.ChannGroupDropDown.Items = {defaults.name};
            app.ChannGroupDropDown.Items{end+1} = '--Select--';
            app.ChannGroupDropDown.Value = '--Select--';
            app.ChannTemplatenameDropDown.Items = {'--Select a template--'};
            app.ChannTemplatenameDropDown.Value = '--Select a template--';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Dataset Configuration';
            addpath('app');
            addpath('bst_templates');
            addpath('config_protocols');
            addpath('external');
            addpath(genpath('functions'));
            addpath('external');
            load_default_params(app);            
        end

        % Button pushed function: SelectBSTButton
        function SelectBSTButtonPushed(app, event)
            try
                folder = uigetdir("Select the Brinstorm path");
                if(isfile(fullfile(folder,'brainstorm.m')))
                    app.BSTpathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the BrainStorm toolbox.',...
                        folder,...
                        ' Please select a correct Brainstorm path.'},'Info');
                    app.BSTpathEditField.Value = '';
                end
            catch
            end
        end

        % Button pushed function: SelectSPMButton
        function SelectSPMButtonPushed(app, event)
            try
                folder = uigetdir("Select the SPM12 path");
                if(isfile(fullfile(folder,'spm.m')))
                    app.SPM12pathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the SPM12 toolbox.',...
                        folser,...
                        ' Please select a correct SPM12 path.'},'Info');
                    app.SPM12pathEditField.Value = '';
                end
            catch
            end
        end

        % Button pushed function: SelectBST_dbButton
        function SelectBST_dbButtonPushed(app, event)
            try
                folder = uigetdir("Select the BST db folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.BSTdbpathEditField.Value = folder;
                else
                    app.BSTdbpathEditField.Value = 'local';
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectWorkspaceButton
        function SelectWorkspaceButtonPushed(app, event)
            try
                folder = uigetdir("Select the Output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.WorkspacepathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectReportsButton
        function SelectReportsButtonPushed(app, event)
            try
                folder = uigetdir("Select the Reports output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.ReportspathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        folder,...
                        ' Please check the folder permission or select another output folder.'},'Info');                    
                end
            catch
            end
        end

        % Selection changed function: 
        % AnatomyconfigurationButtonGroup
        function AnatomyconfigurationButtonGroupSelectionChanged(app, event)
            if(app.Button_BST_Default.Value)
                app.BSTDefaultPanel.Enable = 'on';
                app.HCPTemplatePanel.Enable = 'off';
                app.HCPIndividualPanel.Enable = 'off';
            end
            if(app.Button_HCP_Template.Value)
                app.BSTDefaultPanel.Enable = 'off';
                app.HCPTemplatePanel.Enable = 'on';
                app.HCPIndividualPanel.Enable = 'off';
            end
            if(app.Button_HCP_Individual.Value)
                app.BSTDefaultPanel.Enable = 'off';
                app.HCPTemplatePanel.Enable = 'off';
                app.HCPIndividualPanel.Enable = 'on';
            end
        end

        % Value changed function: ChannGroupDropDown
        function ChannGroupDropDownValueChanged(app, event)
            group_name = app.ChannGroupDropDown.Value;
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                msgbox('Please select the modality first in Generals Tab.','Info');
                app.ChannGroupDropDown.Value = '--Select--';
                return;
            end
            if(isempty(app.BSTpathEditField.Value))
                msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                app.ChannGroupDropDown.Value = '--Select--';
                return;
            end
            if(~isequal(group_name,'--Select--'))
                defaults = jsondecode(fileread(fullfile('bst_templates','bst_layout_default.json')));
                layouts_name = defaults(find(ismember({defaults.name},group_name),1)).contents;               
                app.ChannTemplatenameDropDown.Items = {layouts_name.name};
                app.ChannTemplatenameDropDown.Items{end+1} = '--Select a template--';
                app.ChannTemplatenameDropDown.Value = '--Select a template--';
                msgbox({'Be sure to select the mode and direction of the BrainStorm in <<Generals Tab>>, before selecting the Channel Layout.'},'Info');
            else
                app.ChannTemplatenameDropDown.Items = {'--Select a template--'};
                app.ChannTemplatenameDropDown.Value = '--Select a template--';
                set(app.Channel_UITable, 'Data', []);
            end            
        end

        % Selection changed function: SelectChanneloptionButtonGroup
        function SelectChanneloptionButtonGroupSelectionChanged(app, event)
            if(app.Button_Channel_template.Value)
                app.UseChanneltemplatePanel.Enable = 'on';
                app.UseRawdataPanel.Enable = 'off';
            end
            if(app.Button_Channel_raw_data.Value)
                app.UseChanneltemplatePanel.Enable = 'off';
                app.UseRawdataPanel.Enable = 'on';
            end
        end

        % Value changed function: ChannTemplatenameDropDown
        function ChannTemplatenameDropDownValueChanged(app, event)
            template_name = app.ChannTemplatenameDropDown.Value;
            if(~isequal(template_name,'--Select a template--'))
                template_name = strrep(template_name,' ','_');
                if(isequal(app.ModalityDropDown.Value,'--Select--'))
                    msgbox('Please select the modality in Generals Tab.','Info');
                    app.ChannTemplatenameDropDown.Value = '--Select a template--';
                    return;
                end
                if(isempty(app.BSTpathEditField.Value))
                    msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                    app.ChannTemplatenameDropDown.Value = '--Select a template--';
                    return;
                end
                if(isequal(app.ModalityDropDown.Value,'EEG'))                    
                    layout = load(fullfile(app.BSTpathEditField.Value,'defaults','eeg',app.ChannGroupDropDown.Value,['channel_',template_name,'.mat']));
                end
                if(isequal(app.ModalityDropDown.Value,'MEG'))
                    layout = load(fullfile(app.BSTpathEditField.Value,'defaults','meg',[template_name,'.mat']));
                end                
                for i=1:length(layout.Channel)
                    loc = layout.Channel(i).Loc;
                    Weight = layout.Channel(i).Weight;
                    fields(i).Locs = strcat(num2str(loc(1)),',',num2str(loc(2)),',',num2str(loc(3)));
                end
                order_layout.No         = cellstr(string(1:length(layout.Channel)))';
                order_layout.Name       = {layout.Channel.Name}';
                order_layout.Type       = {layout.Channel.Type}';
                order_layout.Position   = {fields.Locs}';
                order_layout.Comment    = {layout.Channel.Comment}';
                T                       = struct2table(order_layout);
                set(app.Channel_UITable, 'Data', T);
            else
                set(app.Channel_UITable, 'Data', []);
            end
            
        end

        % Value changed function: ModalityDropDown
        function ModalityDropDownValueChanged(app, event)
            modality = app.ModalityDropDown.Value;
            if(isequal(modality,'EEG'))
                app.Button_Channel_template.Value = true;
                app.UseChanneltemplatePanel.Enable = 'on';
                app.UseRawdataPanel.Enable = 'off';
            else
                app.Button_Channel_raw_data.Value = true;
                app.UseChanneltemplatePanel.Enable = 'off';
                app.UseRawdataPanel.Enable = 'on';
            end
        end

        % Button pushed function: SelectRawDataBPathButton
        function SelectRawDataBPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");                
                    app.RawDataBPathEditField = folder;                
            catch
            end
        end

        % Button pushed function: SelectCHPTemplPathButton
        function SelectCHPTemplPathButtonPushed(app, event)
            try
                if(isempty(app.HCPTemplatenameEditField.Value))
                    msgbox({'The HCP template name can not be empty.',...
                        'Please type a HCP Template name.'},'Info');                    
                    return;
                end
                template_name = app.HCPTemplatenameEditField.Value;
                folder = uigetdir("Select the Template basepath");
                if(~isfolder(fullfile(folder,template_name)))
                    msgbox({strcat("There is no folder with ",template_name," in the selected HCP Template folder."),...
                        'Please selcct a correct HCP Template folder or check the Template name filed.'},'Info');
                    app.HCPTemplPathEditField.Value = '';
                    return;
                end
                if(~isfile(fullfile(folder,template_name,'T1w','T1w.nii.gz')))
                    msgbox({'One or more forders are not a HCP structure.',...
                        'Please check the HCP Template folder.'},'Info');
                    app.HCPTemplPathEditField.Value = '';
                    return;
                end                
                app.HCPTemplPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPTemplNBPathButton
        function SelectCHPTemplNBPathButtonPushed(app, event)
            try
                if(isempty(app.HCPTemplatenameEditField.Value))
                    msgbox({'The HCP template name can not be empty.',...
                        'Please type a HCP Template name.'},'Info');
                    return;
                end
                template_name = app.HCPTemplatenameEditField.Value;
                folder = uigetdir("Select the Template basepath");
                if(~isfolder(fullfile(folder,template_name)))
                    msgbox({strcat("There is no folder with ",template_name," in the selected Non-brain folder."),...
                    'Please selcct a correct Non-brain folder or check the Template name filed.'},'Info');
                    app.HCPTemplNBPathEditField.Value = '';
                    return;
                end
                if(~isfile(fullfile(folder,template_name,[template_name,'_outskin_mesh.nii.gz']))...
                        || ~isfile(fullfile(folder,template_name,[template_name,'_outskull_mesh.nii.gz']))...
                        || ~isfile(fullfile(folder,template_name,[template_name,'_inskull_mesh.nii.gz'])))
                msgbox({'One or more forders are not a FSL Bet output command.',...
                    'Please check the Non-brain Template folder.'},'Info');
                app.HCPTemplNBPathEditField.Value = '';
                return;
                end
                app.HCPTemplNBPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPIndividPathButton
        function SelectCHPIndividPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz')))
                         msgbox({'One or more forders are not a HCP structure.',...
                        'Please check the HCP Template folder.'},'Info');
                         break;
                    end                    
                end
                app.HCPIndividPathEditField.Value = folder;                
            catch
            end
        end

        % Button pushed function: SelectCHPTemplatePathButton_4
        function SelectCHPTemplatePathButton_4Pushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))... 
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                         msgbox({'One or more forders are not a FSL Bet output command.',...
                        'Please check the Non-brain Template folder.'},'Info');
                         break;
                    end                    
                end
                app.HCPIndividNBPathEditField.Value = folder;                
            catch
            end
        end

        % Button pushed function: RedefinelabelsButton
        function RedefinelabelsButtonPushed(app, event)
            if(isequal(app.ChannGroupDropDown.Value,'--Select--') || isequal(app.ChannTemplatenameDropDown.Value,'--Select a template--'))
                msgbox('Please select first a channel layout.','Info');
            else
               obj = Redefine_channel_labels();
               obj.load_channel_labels(app.BSTpathEditField.Value,[],app.ChannGroupDropDown.Value,app.ChannTemplatenameDropDown.Value);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SetpropertiesButton = uibutton(app.UIFigure, 'push');
            app.SetpropertiesButton.Text = 'Set properties';
            app.ParamsconfigurationPanel = uipanel(app.UIFigure);
            app.ParamsconfigurationPanel.Title = 'Params configuration';
            app.TabGroup = uitabgroup(app.ParamsconfigurationPanel);
            app.GeneralsTab = uitab(app.TabGroup);
            app.GeneralsTab.Title = 'Generals';
            app.DependenciesPanel = uipanel(app.GeneralsTab);
            app.DependenciesPanel.Title = 'Dependencies';
            app.SelectBSTButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectBSTButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBSTButtonPushed, true);
            app.SelectBSTButton.Text = 'Select';
            app.SelectBST_dbButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectBST_dbButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBST_dbButtonPushed, true);
            app.SelectBST_dbButton.Text = 'Select';
            app.SelectSPMButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectSPMButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSPMButtonPushed, true);
            app.SelectSPMButton.Text = 'Select';
            app.BraninstormpathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.BraninstormpathEditFieldLabel.Text = 'Braninstorm path:';
            app.BSTpathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.BrainstormdbpathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.BrainstormdbpathEditFieldLabel.Text = 'Brainstorm db path:';
            app.BSTdbpathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.BSTdbpathEditField.Value = 'local';
            app.SPM12pathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.SPM12pathEditFieldLabel.Text = 'SPM12 path:';
            app.SPM12pathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.OutputconfigurationsPanel = uipanel(app.GeneralsTab);
            app.OutputconfigurationsPanel.Title = 'Output configurations';
            app.SelectWorkspaceButton = uibutton(app.OutputconfigurationsPanel, 'push');
            app.SelectWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkspaceButtonPushed, true);
            app.SelectWorkspaceButton.Text = 'Select';
            app.SelectReportsButton = uibutton(app.OutputconfigurationsPanel, 'push');
            app.SelectReportsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReportsButtonPushed, true);
            app.SelectReportsButton.Text = 'Select';
            app.WorkspacepathEditFieldLabel = uilabel(app.OutputconfigurationsPanel);
            app.WorkspacepathEditFieldLabel.Text = 'Workspace path:';
            app.WorkspacepathEditField = uieditfield(app.OutputconfigurationsPanel, 'text');
            app.ReportspathEditFieldLabel = uilabel(app.OutputconfigurationsPanel);
            app.ReportspathEditFieldLabel.Text = 'Reports path:';
            app.ReportspathEditField = uieditfield(app.OutputconfigurationsPanel, 'text');
            app.GeneralsparamsPanel = uipanel(app.GeneralsTab);
            app.GeneralsparamsPanel.Title = 'Generals params';
            app.Label = uilabel(app.GeneralsparamsPanel);
            app.Label.Text = '';
            app.ModalityDropDownLabel = uilabel(app.GeneralsparamsPanel);
            app.ModalityDropDownLabel.Text = 'Modality:';
            app.ModalityDropDown = uidropdown(app.GeneralsparamsPanel);
            app.ModalityDropDown.Items = {'--Select--', 'EEG', 'MEG'};
            app.ModalityDropDown.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDownValueChanged, true);
            app.ModalityDropDown.Value = '--Select--';
            app.ProtocolnameEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.ProtocolnameEditFieldLabel.Text = 'Protocol name:';
            app.ProtocolnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.SubjectsperprotocolEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.SubjectsperprotocolEditFieldLabel.Text = 'Subjects per protocol:';
            app.SubjectsperprotocolEditField = uieditfield(app.GeneralsparamsPanel, 'numeric');
            app.SubjectsperprotocolEditField.Limits = [5 100];
            app.SubjectsperprotocolEditField.Value = 10;
            app.AnatomicalstructureTab = uitab(app.TabGroup);
            app.AnatomicalstructureTab.Title = 'Anatomical structure';
            app.AnatomyconfigurationButtonGroup = uibuttongroup(app.AnatomicalstructureTab);
            app.AnatomyconfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnatomyconfigurationButtonGroupSelectionChanged, true);
            app.AnatomyconfigurationButtonGroup.Title = 'Anatomy configuration';
            app.Button_BST_Default = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_BST_Default.Text = '';
            app.Button_BST_Default.Value = true;
            app.Button_HCP_Template = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_HCP_Template.Text = '';
            app.Button_HCP_Individual = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_HCP_Individual.Text = '';
            app.BSTDefaultPanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.BSTDefaultPanel.Title = 'BST Default';
            app.TemplatenameDropDownLabel = uilabel(app.BSTDefaultPanel);
            app.TemplatenameDropDownLabel.Text = 'Template name:';
            app.AnatomyTemplateDropDown = uidropdown(app.BSTDefaultPanel);
            app.HCPTemplatePanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.HCPTemplatePanel.Title = 'HCP Template';
            app.SelectCHPTemplPathButton = uibutton(app.HCPTemplatePanel, 'push');
            app.SelectCHPTemplPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPTemplPathButtonPushed, true);
            app.SelectCHPTemplPathButton.Text = 'Select';
            app.AnatomypathLabel = uilabel(app.HCPTemplatePanel);
            app.AnatomypathLabel.Text = 'Anatomy path:';
            app.HCPTemplPathEditField = uieditfield(app.HCPTemplatePanel, 'text');
            app.SelectCHPTemplNBPathButton = uibutton(app.HCPTemplatePanel, 'push');
            app.SelectCHPTemplNBPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPTemplNBPathButtonPushed, true);
            app.SelectCHPTemplNBPathButton.Text = 'Select';
            app.NonbrainsurfacesLabel = uilabel(app.HCPTemplatePanel);
            app.NonbrainsurfacesLabel.Text = 'Non-brain surfaces:';
            app.HCPTemplNBPathEditField = uieditfield(app.HCPTemplatePanel, 'text');
            app.TemplatenameEditFieldLabel = uilabel(app.HCPTemplatePanel);
            app.TemplatenameEditFieldLabel.Text = 'Template name:';
            app.HCPTemplatenameEditField = uieditfield(app.HCPTemplatePanel, 'text');
            app.HCPIndividualPanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.HCPIndividualPanel.Title = 'HCP Individual';
            app.SelectCHPIndividPathButton = uibutton(app.HCPIndividualPanel, 'push');
            app.SelectCHPIndividPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPIndividPathButtonPushed, true);
            app.SelectCHPIndividPathButton.Text = 'Select';
            app.SelectCHPTemplatePathButton_4 = uibutton(app.HCPIndividualPanel, 'push');
            app.SelectCHPTemplatePathButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPTemplatePathButton_4Pushed, true);
            app.SelectCHPTemplatePathButton_4.Text = 'Select';
            app.AnatomypathLabel_2 = uilabel(app.HCPIndividualPanel);
            app.AnatomypathLabel_2.Text = 'Anatomy path:';
            app.HCPIndividPathEditField = uieditfield(app.HCPIndividualPanel, 'text');
            app.NonbrainsurfacesLabel_2 = uilabel(app.HCPIndividualPanel);
            app.NonbrainsurfacesLabel_2.Text = 'Non-brain surfaces:';
            app.HCPIndividNBPathEditField = uieditfield(app.HCPIndividualPanel, 'text');
            app.SurfacesresolutionPanel = uipanel(app.AnatomicalstructureTab);
            app.SurfacesresolutionPanel.Title = 'Surfaces resolution';
            app.NoverticesofHeadEditFieldLabel = uilabel(app.SurfacesresolutionPanel);
            app.NoverticesofHeadEditFieldLabel.Text = 'No. vertices of Head:';
            app.NoverticesofHeadEditField = uieditfield(app.SurfacesresolutionPanel, 'numeric');
            app.NoverticesofHeadEditField.Limits = [5000 15000];
            app.NoverticesofHeadEditField.Value = 8000;
            app.NoverticesofSkullEditFieldLabel = uilabel(app.SurfacesresolutionPanel);
            app.NoverticesofSkullEditFieldLabel.Text = 'No. vertices of Skull:';
            app.NoverticesofSkullEditField = uieditfield(app.SurfacesresolutionPanel, 'numeric');
            app.NoverticesofSkullEditField.Limits = [5000 15000];
            app.NoverticesofSkullEditField.Value = 8000;
            app.NoverticesofCortexEditFieldLabel = uilabel(app.SurfacesresolutionPanel);
            app.NoverticesofCortexEditFieldLabel.Text = 'No. vertices of Cortex:';
            app.NoverticesofCortexEditField = uieditfield(app.SurfacesresolutionPanel, 'numeric');
            app.NoverticesofCortexEditField.Limits = [5000 15000];
            app.NoverticesofCortexEditField.Value = 8000;
            app.ChannelTab = uitab(app.TabGroup);
            app.ChannelTab.Title = 'Channel';
            app.SelectChanneloptionButtonGroup = uibuttongroup(app.ChannelTab);
            app.SelectChanneloptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectChanneloptionButtonGroupSelectionChanged, true);
            app.SelectChanneloptionButtonGroup.Title = 'Select Channel option';
            app.Button_Channel_template = uiradiobutton(app.SelectChanneloptionButtonGroup);
            app.Button_Channel_template.Text = '';
            app.Button_Channel_template.Value = true;
            app.Button_Channel_raw_data = uiradiobutton(app.SelectChanneloptionButtonGroup);
            app.Button_Channel_raw_data.Text = '';
            app.UseChanneltemplatePanel = uipanel(app.SelectChanneloptionButtonGroup);
            app.UseChanneltemplatePanel.Title = 'Use Channel template';
            app.GroupDropDownLabel = uilabel(app.UseChanneltemplatePanel);
            app.GroupDropDownLabel.Text = 'Group:';
            app.ChannGroupDropDown = uidropdown(app.UseChanneltemplatePanel);
            app.ChannGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannGroupDropDownValueChanged, true);
            app.TemplatenameDropDownLabel_2 = uilabel(app.UseChanneltemplatePanel);
            app.TemplatenameDropDownLabel_2.Text = 'Template name:';
            app.ChannTemplatenameDropDown = uidropdown(app.UseChanneltemplatePanel);
            app.ChannTemplatenameDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannTemplatenameDropDownValueChanged, true);
            app.Channel_UITable = uitable(app.UseChanneltemplatePanel);
            app.Channel_UITable.ColumnName = {'No.'; 'Name'; 'Type'; 'Location'; 'Comment'};
            app.Channel_UITable.RowName = {};
            app.RedefinelabelsButton = uibutton(app.UseChanneltemplatePanel, 'push');
            app.RedefinelabelsButton.ButtonPushedFcn = createCallbackFcn(app, @RedefinelabelsButtonPushed, true);
            app.RedefinelabelsButton.Text = 'Redefine labels';
            app.UseRawdataPanel = uipanel(app.SelectChanneloptionButtonGroup);
            app.UseRawdataPanel.Title = 'Use Raw data';
            app.SelectRawDataBPathButton = uibutton(app.UseRawdataPanel, 'push');
            app.SelectRawDataBPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawDataBPathButtonPushed, true);
            app.SelectRawDataBPathButton.Text = 'Select';
            app.BasepathLabel = uilabel(app.UseRawdataPanel);
            app.BasepathLabel.Text = 'Base path:';
            app.RawDataBPathEditField = uieditfield(app.UseRawdataPanel, 'text');
            app.SelectRawDataFPathButton = uibutton(app.UseRawdataPanel, 'push');
            app.SelectRawDataFPathButton.Text = 'Select';
            app.FilelocationLabel = uilabel(app.UseRawdataPanel);
            app.FilelocationLabel.Text = 'File location:';
            app.RawDataFPathEditField = uieditfield(app.UseRawdataPanel, 'text');
            app.FormatDropDownLabel = uilabel(app.UseRawdataPanel);
            app.FormatDropDownLabel.Text = 'Format:';
            app.FormatDropDown = uidropdown(app.UseRawdataPanel);
            app.FormatDropDown.Items = {'-Select-', ''};
            app.FormatDropDown.Value = '-Select-';
            app.PreprocesseddataTab = uitab(app.TabGroup);
            app.PreprocesseddataTab.Title = 'Preprocessed data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dataset_configuration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end